classdef appTrabalhoAM2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        PginaPrincipalMenu  matlab.ui.container.Menu
        AutoresMenu         matlab.ui.container.Menu
        UITable             matlab.ui.control.Table
        DadosPVI            matlab.ui.container.Panel
        ftyLabel            matlab.ui.control.Label
        eF                  matlab.ui.control.EditField
        aLabel              matlab.ui.control.Label
        eA                  matlab.ui.control.NumericEditField
        eB                  matlab.ui.control.NumericEditField
        bLabel              matlab.ui.control.Label
        eN                  matlab.ui.control.NumericEditField
        NLabel              matlab.ui.control.Label
        Y0Label             matlab.ui.control.Label
        eY0                 matlab.ui.control.NumericEditField
        bAtualizar          matlab.ui.control.Button
        bgMetodos           matlab.ui.container.ButtonGroup
        rbEuler             matlab.ui.control.RadioButton
        rbEulerM            matlab.ui.control.RadioButton
        rbRK2               matlab.ui.control.RadioButton
        rbRK4               matlab.ui.control.RadioButton
        rbODE45             matlab.ui.control.RadioButton
        rbODE23             matlab.ui.control.RadioButton
        rbTodos             matlab.ui.control.RadioButton
        bSair               matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms y(t)
            strF = app.eF.Value;
            f =@(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;   
            n = app.eN.Value;           
            y0 = app.eY0.Value; 
            
            escolhabgMetodos = app.bgMetodos.SelectedObject;
            metodoSelecionado = find([app.rbEuler,...
                                     app.rbEulerM,...
                                     app.rbRK2,...
                                     app.rbRK4,...
                                     app.rbODE45,...
                                     app.rbODE23,...
                                     app.rbTodos]==escolhabgMetodos);
            
            erroFuncao = MException("MATLAB:bAtualizar:badF",...
                                    "Introduza uma função em t e y");
            % outras
            
            
            try
                try
                    z = f(t,y);
                    app.eF.BackgroundColor = "w";
                catch 
                    app.eF.BackgroundColor = [1,0,0]; %[r,g,b]                  
                    throw(erroFuncao)
                end
                
                
                yEuler = MEuler(f,a,b,n,y0);
                yEulerM = MeulerMelhorado(f,a,b,n,y0);
                yRK2 = NRK2(f,a,b,n,y0); 
                yRK4 = NRK4(f,a,b,n,y0); 
                yODE23 = NODE23(f,a,b,n,y0);
                yODE45= NODE45(f,a,b,n,y0);
                
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                
                g =@(t) eval(vectorize(char(sExata)));
                
                h = (b-a)/n;
                t = a:h:b;
                yExata = g(t);
                erroEuler = abs(yExata-yEuler);
                erroEulerM = abs(yExata-yEulerM);
                erroRK2 = abs(yExata-yRK2);
                erroRK4 = abs(yExata-yRK4);
                erroODE23 = abs(yExata-yODE23);
                erroODE45 = abs(yExata-yODE45);

                plot(app.UIAxes,t,yExata,"-ro")
                hold(app.UIAxes,"on")  
                
                switch metodoSelecionado
                    case 1
                        
                         plot(app.UIAxes,t,yEuler,"-g*")  
                         legenda = {'Exata','Euler'};
                         tabela = [t.',yExata.',yEuler.',erroEuler.'];
                         nomeColunas = {'t','Exata','Euler','erroEuler'};
                    case 2
                        
                         plot(app.UIAxes,t,yEulerM,"-y+")  
                         legenda = {'Exata','Método de Euler Melhorado'};
                         tabela = [t.',yExata.',yEulerM.',erroEulerM.'];
                         nomeColunas = {'t','Exata','Euler Método Melhorado','erroEulerM'};
                    case 3
                        
                         plot(app.UIAxes,t,yRK2,"-b+")  
                         legenda = {'Exata','RK2'};
                         tabela = [t.',yExata.',yRK2.',erroRK2.'];
                         nomeColunas = {'t','Exata','RK2','erroRK2'}; 
                    case 4
                        
                         plot(app.UIAxes,t,yRK4,"-c+")  
                         legenda = {'Exata','RK4'};
                         tabela = [t.',yExata.',yRK4.',erroRK4.'];
                         nomeColunas = {'t','Exata','RK4','erroRK4'}; 
                        
                        
                    case 5
                   
                         plot(app.UIAxes,t,yODE45,"-y*")  
                         legenda = {'Exata','ODE45'};
                         tabela = [t.',yExata.',yODE45.',erroODE45.'];
                         nomeColunas = {'t','Exata','ODE45','erroODE45'};
                         
                    case 6
                        
                         plot(app.UIAxes,t,yODE23,"-m*")  
                         legenda = {'Exata','ODE23'};
                         tabela = [t.',yExata.',yODE23.',erroODE23.'];
                         nomeColunas = {'t','Exata','ODE23','erroODE23'};                     
                    case 7
                        
                         plot(app.UIAxes,t,yEuler,"-g*")
                         plot(app.UIAxes,t,yEulerM,"-y+")
                         plot(app.UIAxes,t,yRK2,"-b*")
                         plot(app.UIAxes,t,yRK4,"-c*")
                         plot(app.UIAxes,t,yODE45,"-y+") 
                         plot(app.UIAxes,t,yODE23,"-m+") 
                         legenda = {'Exata','Euler','Euler Melhorado','RK2','RK4','ODE45','ODE23'};
                         tabela = [t.',yExata.',yEuler.',yEulerM.',yRK2.',yRK4.',yODE45.',yODE23.',...
                                   erroEuler.',erroEulerM.',erroRK2.',erroRK4.',erroODE45.',erroODE23.'];
                         nomeColunas = {'t','Exata','Euler','Euler Método Melhorado','RK2','RK4','ODE45','ODE23',...
                                        'erroEuler','erroEulerM','erroRK2','RK4','erroODE45','erroODE23'};
                        
                end               
 
                T = array2table(tabela);
                app.UITable.Data = T;
                app.UITable.ColumnName = nomeColunas;

                hold(app.UIAxes,"off")
                grid(app.UIAxes,"on")
                legend(app.UIAxes,legenda)
                
            catch ME
                errordlg(ME.message,"Erro","modal")
            end

        end

        % Button pushed function: bSair
        function bSairButtonPushed(app, event)
             SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            app.delete;
            autores;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PginaPrincipalMenu = uimenu(app.UIFigure);
            app.PginaPrincipalMenu.Text = 'Página Principal';
            app.AutoresMenu = uimenu(app.UIFigure);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.DadosPVI = uipanel(app.UIFigure);
            app.DadosPVI.Title = 'Dados do PVI';
            app.ftyLabel = uilabel(app.DadosPVI);
            app.ftyLabel.Text = 'f(t,y) = ';
            app.eF = uieditfield(app.DadosPVI, 'text');
            app.eF.Value = '-2*t*y';
            app.aLabel = uilabel(app.DadosPVI);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.DadosPVI, 'numeric');
            app.eB = uieditfield(app.DadosPVI, 'numeric');
            app.eB.Value = 1.5;
            app.bLabel = uilabel(app.DadosPVI);
            app.bLabel.Text = 'b';
            app.eN = uieditfield(app.DadosPVI, 'numeric');
            app.eN.Value = 10;
            app.NLabel = uilabel(app.DadosPVI);
            app.NLabel.Text = 'N';
            app.Y0Label = uilabel(app.DadosPVI);
            app.Y0Label.Text = 'Y0';
            app.eY0 = uieditfield(app.DadosPVI, 'numeric');
            app.eY0.Value = 2;
            app.bAtualizar = uibutton(app.UIFigure, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.bgMetodos = uibuttongroup(app.UIFigure);
            app.bgMetodos.Title = 'Métodos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbODE45 = uiradiobutton(app.bgMetodos);
            app.rbODE45.Text = 'ODE45';
            app.rbODE23 = uiradiobutton(app.bgMetodos);
            app.rbODE23.Text = 'ODE23';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos os Métodos';
            app.bSair = uibutton(app.UIFigure, 'push');
            app.bSair.ButtonPushedFcn = createCallbackFcn(app, @bSairButtonPushed, true);
            app.bSair.Text = 'Sair';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTrabalhoAM2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end