classdef appTrabalhoAM2_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MenuTab                         matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        DadosPVI                        matlab.ui.container.Panel
        ftuvLabel                       matlab.ui.control.Label
        eF                              matlab.ui.control.EditField
        ALabel                          matlab.ui.control.Label
        eA                              matlab.ui.control.NumericEditField
        eB                              matlab.ui.control.NumericEditField
        BLabel                          matlab.ui.control.Label
        eN                              matlab.ui.control.NumericEditField
        NLabel                          matlab.ui.control.Label
        U0Label                         matlab.ui.control.Label
        eU0                             matlab.ui.control.NumericEditField
        gtuvLabel                       matlab.ui.control.Label
        eG                              matlab.ui.control.EditField
        V0Label                         matlab.ui.control.Label
        eV0                             matlab.ui.control.NumericEditField
        bgMetodos                       matlab.ui.container.ButtonGroup
        rbEuler                         matlab.ui.control.RadioButton
        rbEulerM                        matlab.ui.control.RadioButton
        rbRK2                           matlab.ui.control.RadioButton
        rbRK4                           matlab.ui.control.RadioButton
        rbTodos                         matlab.ui.control.RadioButton
        ProblemasdinmicosLabel          matlab.ui.control.Label
        ddProblemas                     matlab.ui.control.DropDown
        bAtualizar                      matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        AutoresTab                      matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        DiogoSilva2020138473Label       matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        HugoFerreira2020128305Label     matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        RbenMendes2020138473Label       matlab.ui.control.Label
        AM2MatemticaComputacionalLabel  matlab.ui.control.Label
        ISECEngenhariaInformticaLabel   matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
             syms u(t) v(t)
            strF = app.eF.Value;
            strG = app.eG.Value;
            f =@(t,u,v) eval(vectorize(strF));
            g =@(t,u,v) eval(vectorize(strG));
            a = app.eA.Value;
            b = app.eB.Value;   
            n = app.eN.Value;           
            u0 = app.eU0.Value; 
            v0 = app.eV0.Value;
            
            escolhabgMetodos = app.bgMetodos.SelectedObject;
            metodoSelecionado = find([app.rbEuler,...
                                     app.rbEulerM,...
                                     app.rbRK2,...
                                     app.rbRK4,...
                                     app.rbTodos]==escolhabgMetodos);
            
            erroFuncao = MException("MATLAB:bAtualizar:badF",...
                                    "Introduza uma função em t e y");
            % outras
            
            
            try
                try
                    z = g(t,u,v);
                    app.eG.BackgroundColor = "w";
                catch 
                    app.eG.BackgroundColor = [1,0,0]; %[r,g,b]                  
                    throw(erroFuncao)
                end
                
                
                eqns = [diff(u,t)==v,diff(v,t)==-16*u];
                ci = [u(0)==u0,v(0)==v0];
                [sExataU,sExataV] = dsolve(eqns,ci);
                
                h =@(t) eval(vectorize(char(sExataU)));
                
                
                [t,uEuler,~] = NEulerSED(f,g,a,b,n,u0,v0);
                [t,uEulerM,~] = NEulerMSED(f,g,a,b,n,u0,v0);
                [~,uRK2,~] = NRK2SED(f,g,a,b,n,u0,v0);
                [~,uRK4,~] = NRK4SED(f,g,a,b,n,u0,v0)
                
                uExata = h(t);
                
                erroEuler = abs(uExata-uEuler);
                erroEulerM = abs(uExata-uEulerM);
                erroRK2 = abs(uExata-uRK2);
                erroRK4 = abs(uExata-uRK4);

                plot(app.UIAxes,t,uExata,"-ro")
                hold(app.UIAxes,"on")  
                
                switch metodoSelecionado
                    case 1
                         plot(app.UIAxes,t,uEuler,"-g*")  
                         legenda = {'Exata','Euler'};
                         tabela = [t.',uExata.',uEuler.',erroEuler.'];
                         nomeColunas = {'t','Exata','Euler','erroEuler'};
                    case 2
                         plot(app.UIAxes,t,uEulerM,"-b+")   
                         legenda = {'Exata','Euler'};
                         tabela = [t.',uExata.',uEulerM.',erroEulerM.'];
                         nomeColunas = {'t','Exata','EulerM','erroEulerM'}
                    case 3
                         plot(app.UIAxes,t,uRK2,"-kX") 
                         legenda = {'Exata','RK2'};
                         tabela = [t.',uExata.',uRK2.',erroRK2.'];
                         nomeColunas = {'t','Exata','RK2','erroRK2'}; 
                    case 4
                         plot(app.UIAxes,t,uRK4,"-m.")   
                         legenda = {'Exata','RK4'};
                         tabela = [t.',uExata.',uRK4.',erroRK4.'];
                         nomeColunas = {'t','Exata','RK4','erroRK4'};                                                 
                    case 5
                         plot(app.UIAxes,t,uEuler,"-g*")
                         plot(app.UIAxes,t,uEulerM,"-b+") 
                         plot(app.UIAxes,t,uRK2,"-kX")
                         plot(app.UIAxes,t,uRK4,"-m.") 
                         legenda = {'Exata','Euler','EulerM','RK2','RK4'};
                         tabela = [t.',uExata.',uEuler.',uEulerM.',uRK2.',uRK4.',erroEuler.',erroEulerM.',erroRK2.',erroRK4.'];
                         nomeColunas = {'t','Exata','Euler','EulerM','RK2','RK4','erroEuler','erroEulerM','erroRK2','erroRK4'};
                        
                end               
 
                T = array2table(tabela);
                app.UITable.Data = T;
                app.UITable.ColumnName = nomeColunas;

                hold(app.UIAxes,"off")
                grid(app.UIAxes,"on")
                legend(app.UIAxes,legenda)
                
            catch ME
                errordlg(ME.message,"Erro","modal")
            end

        end

        % Value changed function: ddProblemas
        function ddProblemasValueChanged(app, event)
           value = app.ddProblemas.Value;
            switch value
                case "Pêndulo"
                    app.eG.Editable = 'off';
                    strG = "-sin(u)-0.3*v";
                    a = 0; b = 15; n = 100;
                    u0 = pi/2; v0 = 0;
                case "Mola_Massa sem Amortecimento"
                    app.eG.Editable = 'off';
                    strG = "-16*u";
                    a = 0; b = 4; n = 100;
                    u0 = 9; v0 = 0;
                case "Mola_Massa com Amortecimento"
                    app.eG.Editable = 'off';
                    strG = "-10*v-25*u";
                    a = 0; b = 10; n = 100;
                    u0 = 9; v0 = 0;
                case "Circuito Eletrico"
                    app.eG.Editable = 'off';
                    strG = "-(4/3)*v-(16/3)*u";
                    a = 0; b = 10; n = 100;
                    u0 = 9; v0 = 0;
                case "Vazio"
                    app.eG.Editable = 'on';
                    strG = " ";
                     a = 0; b = 0; n = 0;
                    u0 = 9; v0 = 0;
            end
            app.eG.Value = strG;
            app.eA.Value = a; app.eB.Value = b; app.eN.Value=n;
            app.eU0.Value = u0; app.eV0.Value = v0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.Panel = uipanel(app.MenuTab);
            app.DadosPVI = uipanel(app.Panel);
            app.DadosPVI.Title = 'Dados do PVI';
            app.ftuvLabel = uilabel(app.DadosPVI);
            app.ftuvLabel.Text = 'f(t,u,v) = ';
            app.eF = uieditfield(app.DadosPVI, 'text');
            app.eF.Editable = 'off';
            app.eF.Value = 'v';
            app.ALabel = uilabel(app.DadosPVI);
            app.ALabel.Text = 'A';
            app.eA = uieditfield(app.DadosPVI, 'numeric');
            app.eB = uieditfield(app.DadosPVI, 'numeric');
            app.eB.Value = 1.5;
            app.BLabel = uilabel(app.DadosPVI);
            app.BLabel.Text = 'B';
            app.eN = uieditfield(app.DadosPVI, 'numeric');
            app.eN.Value = 10;
            app.NLabel = uilabel(app.DadosPVI);
            app.NLabel.Text = 'N';
            app.U0Label = uilabel(app.DadosPVI);
            app.U0Label.Text = 'U0';
            app.eU0 = uieditfield(app.DadosPVI, 'numeric');
            app.eU0.Value = 1.571;
            app.gtuvLabel = uilabel(app.DadosPVI);
            app.gtuvLabel.Text = 'g(t,u,v)= ';
            app.eG = uieditfield(app.DadosPVI, 'text');
            app.eG.Editable = 'off';
            app.eG.Value = '-sin(u)-0.3*v';
            app.V0Label = uilabel(app.DadosPVI);
            app.V0Label.Text = 'V0';
            app.eV0 = uieditfield(app.DadosPVI, 'numeric');
            app.bgMetodos = uibuttongroup(app.Panel);
            app.bgMetodos.Title = 'Métodos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos os Métodos';
            app.ProblemasdinmicosLabel = uilabel(app.Panel);
            app.ProblemasdinmicosLabel.Text = 'Problemas dinâmicos';
            app.ddProblemas = uidropdown(app.Panel);
            app.ddProblemas.Items = {'Pêndulo', 'Mola_Massa sem Amortecimento', 'Mola_Massa com Amortecimento', 'Circuito Eletrico', 'Vazio'};
            app.ddProblemas.ValueChangedFcn = createCallbackFcn(app, @ddProblemasValueChanged, true);
            app.ddProblemas.Value = 'Pêndulo';
            app.bAtualizar = uibutton(app.Panel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.Panel_2 = uipanel(app.MenuTab);
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, 'Gráfico ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AutoresTab = uitab(app.TabGroup);
            app.AutoresTab.Title = 'Autores';
            app.Image = uiimage(app.AutoresTab);
            app.Image.ImageSource = 'diogo.jpg';
            app.DiogoSilva2020138473Label = uilabel(app.AutoresTab);
            app.DiogoSilva2020138473Label.Text = 'Diogo Silva -2020138473';
            app.Image_2 = uiimage(app.AutoresTab);
            app.Image_2.ImageSource = 'hugo.png';
            app.HugoFerreira2020128305Label = uilabel(app.AutoresTab);
            app.HugoFerreira2020128305Label.Text = 'Hugo Ferreira - 2020128305';
            app.Image_3 = uiimage(app.AutoresTab);
            app.Image_3.ImageSource = 'ruben.jpg';
            app.RbenMendes2020138473Label = uilabel(app.AutoresTab);
            app.RbenMendes2020138473Label.Text = 'Rúben Mendes- 2020138473';
            app.AM2MatemticaComputacionalLabel = uilabel(app.AutoresTab);
            app.AM2MatemticaComputacionalLabel.Text = 'AM2 - Matemática Computacional ';
            app.ISECEngenhariaInformticaLabel = uilabel(app.AutoresTab);
            app.ISECEngenhariaInformticaLabel.Text = 'ISEC - Engenharia Informática';
            app.Image2 = uiimage(app.AutoresTab);
            app.Image2.ImageSource = 'LOGO-ISEC-IPC_sem-fundo-copy_fb.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTrabalhoAM2_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end