classdef appMaquiaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        tgFrVr                          matlab.ui.container.Tab
        pDados                          matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        FunoLabel                       matlab.ui.control.Label
        eF                              matlab.ui.control.EditField
        eALabel                         matlab.ui.control.Label
        eA                              matlab.ui.control.NumericEditField
        eH                              matlab.ui.control.NumericEditField
        eHLabel                         matlab.ui.control.Label
        eB                              matlab.ui.control.NumericEditField
        eBLabel                         matlab.ui.control.Label
        eN                              matlab.ui.control.NumericEditField
        NLabel                          matlab.ui.control.Label
        bAtualizar                      matlab.ui.control.Button
        bgMetodos                       matlab.ui.container.ButtonGroup
        Progressivas                    matlab.ui.control.RadioButton
        Regressivas                     matlab.ui.control.RadioButton
        Regressivas3                    matlab.ui.control.RadioButton
        Progressivas3                   matlab.ui.control.RadioButton
        Centradas3                      matlab.ui.control.RadioButton
        Derivada                        matlab.ui.control.RadioButton
        bginteNum                       matlab.ui.container.ButtonGroup
        trapezios                       matlab.ui.control.RadioButton
        simpson                         matlab.ui.control.RadioButton
        reaEditFieldLabel               matlab.ui.control.Label
        area                            matlab.ui.control.NumericEditField
        UIMostrador                     matlab.ui.control.UIAxes
        pResultados                     matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        tgFr2Vr                         matlab.ui.container.Tab
        pDados_2                        matlab.ui.container.Panel
        Panel_2                         matlab.ui.container.Panel
        fxyLabel                        matlab.ui.control.Label
        eF_2                            matlab.ui.control.EditField
        eA_2                            matlab.ui.control.NumericEditField
        eH_2                            matlab.ui.control.NumericEditField
        eB_2                            matlab.ui.control.NumericEditField
        eA_3                            matlab.ui.control.NumericEditField
        eH_3                            matlab.ui.control.NumericEditField
        eB_3                            matlab.ui.control.NumericEditField
        bAtualizar_2                    matlab.ui.control.Button
        bgMetodos3                      matlab.ui.container.ButtonGroup
        Harmonica                       matlab.ui.control.RadioButton
        eHarmonica                      matlab.ui.control.EditField
        pResultados_2                   matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        AutoresTab                      matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        DiogoSilva2020138473Label       matlab.ui.control.Label
        HugoFerreira2020128305Label     matlab.ui.control.Label
        RbenMendes2020138473Label       matlab.ui.control.Label
        ISECEngenhariaInformticaLabel   matlab.ui.control.Label
        AM2MatemticaComputacionalLabel  matlab.ui.control.Label
    end

    
    methods (Access = public)
                    
            function [x,y,dydx] = NDerivadaDFP(~,f,a,b,h,y)
                %UNTITLED Summary of this function goes here
                %   Detailed explanation goes here
                %   29/04/2021  Arménio Correia armenioc@isec.pt
                x = a:h:b;
                n = length(x);
                if nargin == 5
                    y = f(x);
                end
                dydx = zeros(1,n);
                for i = 1:n-1
                    dydx(i) = (y(i+1)-y(i))/h;
                end
                dydx(n) = dydx(n-1);
                %dydx(n) = (y(n)-y(n-1))/h;
            end
            
            function [x,y,dydx]=DFRegressivas_2(~,f,a,b,h,y)
                x=a:h:b;
                n=length(x);
                    if nargin==5 
                        y=f(x);
                    end
                dydx=zeros(1,n);
                dydx(1)=(y(2)-y(1))/h;
                    for i=2:n
                        dydx(i)=(y(i)-y(i-1))/h;
                    end
            end
            
            function YN = isHarmonica(~,f)
                syms z(x,y)
                if(diff(f,x,2)+diff(f,y,2)==0) %equação de laplace
                    YN=1;
                else
                    YN=0;
                end
            end
            
            function [x,y,dydx]=DFRegressivas_3(~,f,a,b,h,y)
                x=a:h:b;
                n=length(x);
                    if nargin==5
                        y=f(x);
                    end
                dydx=zeros(1,n);
                dydx(1)=( (-3)*y(1) + 4*y(2) - y(3) )/(2*h);
                dydx(2)=( (-3)*y(2) + 4*y(3) - y(4) )/(2*h);
                    for i=3:n
                        dydx(i)=( y(i-2) - 4*y(i-1) + 3*y(i) )/(2*h);
                    end
            end
            
            function [x,y,dydx]=DFProgressivas_3(~,f,a,b,h,y)
                x=a:h:b;
                n=length(x);
                    if nargin==5 %o nargin conta os inputs de uma função 
                        y=f(x);
                    end
                dydx=zeros(1,n);
                    for i=1:n-2
                        dydx(i)=( (-3)*y(i) + 4*y(i+1) - y(i+2) ) / (2*h);
                    end
                dydx(n-1)=( y(n-3) - 4*y(n-2) + 3*y(n-1) )/(2*h);
                dydx(n)=( y(n-2) - 4*y(n-1) + 3*y(n) )/(2*h);
            end
            
            function [x,y,dydx]=DFCentradas_3(~,f,a,b,h,y)
                x=a:h:b;
                n=length(x);
                if nargin==5 %o nargin conta os inputs de uma função 
                    y=f(x);
                end
                dydx=zeros(1,n);
                dydx(1)=(y(2)-y(1))/h;
                for i=2:n-1
                    dydx(i)=(y(i+1)-y(i-1))/(2*h);
                end
                dydx(n)=(y(n)-y(n-1))/h;
            end
            
            function [x,y,dydx]=DFDerivada_2(~,f,a,b,h,y)
                x=a:h:b;
                n=length(x);
                if nargin==5 %o nargin conta os inputs de uma função 
                    y=f(x);
                end
                dydx=zeros(1,n);
                dydx(1)=(y(3)-2*y(2)+y(1))/(h^2); %calcula a segunda derivada
                for i=2:n-1
                    dydx(i)=(y(i+1)-2*y(i)+y(i-1))/(h^2);%formula da dfDerivada
                end
                dydx(n)=(y(n)-2*y(n-1)+y(n-2))/(h^2);
            end
            
            function s = RSimpson(~,f,a,b,n)
                h = (b-a)/n;
                 x=a;
                s = 0;
              
                    for i=1:n-1
                        x=x+h;
                            if mod(i,2)==0 %mod
                                s = s+2*f(x);
                            else
                                s = s+4*f(x);
                            end
                    end
                %s = h*(f(a)+f(b)+s)/3;
                s = h*(f(a)+f(b)+s)/3;
            end
           
            function t = RTrapezios(~,f,a,b,n)
                h=(b-a)/n;
                x=a;
                s=0;
                    for i=1:n-1
                        x=x+h;
                        s=s+f(x);
                    end
                t = h*(f(a)+2*s+f(b))/2;
            end


        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms y(x)
            strF = app.eF.Value;
            f = @(x) eval(vectorize(strF));
            a = app.eA.Value; 
            h = app.eH.Value; 
            b = app.eB.Value; 
            y=f(x);
            escolhabgMetodos = app.bgMetodos.SelectedObject;
            metodoSelecionado = find([app.Progressivas,...
                                     app.Regressivas,...
                                     app.Regressivas3,...
                                      app.Progressivas3,...
                                      app.Centradas3,...
                                      app.Derivada]==escolhabgMetodos);


            switch metodoSelecionado
                    case 1
                         try
                            dydxSym = diff(f(x));
                            g =@(x) eval(vectorize(char(dydxSym)));
                            [x,y,dydxDFP] = NDerivadaDFP(app,f,a,b,h);
                            dydxExata = g(x);
                            erroDFP = abs(dydxExata -dydxDFP);
                            tabela = array2table([x.',y.',dydxExata.', dydxDFP.',erroDFP.']);
                            app.UITable.ColumnName = {"X","Y","Derivada Exata","Derivada Aproximada","Erro"};
                            app.UITable.Data = tabela;
                            plot(app.UIAxes,x,y,"-r+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x, dydxExata, "-bo")
                            plot(app.UIAxes,x,dydxDFP,"-g*")
                            hold(app.UIAxes,"off")
                            formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                            cla(app.UIMostrador)
                            %app.UIMostrador.Toolbar.Visible = 'off';
                            text(app.UIMostrador,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)     
                            
                        catch ME
                            errordlg(ME.message,"ERRO","modal")
                         end
                    
                    case 2   
                        try
                            dydxSym = diff(f(x));
                            g =@(x) eval(vectorize(char(dydxSym)));
                            [x,y,dydxDFR] = DFRegressivas_2(app,f,a,b,h);
                            dydxExata = g(x);
                            erroDFR = abs(dydxExata -dydxDFR);
                            tabela = array2table([x.',y.',dydxExata.', dydxDFR.',erroDFR.']);
                            app.UITable.ColumnName = {"X","Y","Derivada Exata","Derivada Aproximada","Erro"};
                            app.UITable.Data = tabela;
                            plot(app.UIAxes,x,y,"-r+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x, dydxExata, "-bo")
                            plot(app.UIAxes,x,dydxDFR,"-g*")
                            hold(app.UIAxes,"off")
                            formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                            cla(app.UIMostrador)
                            %app.UIMostrador.Toolbar.Visible = 'off';
                            text(app.UIMostrador,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)     
                          catch ME
                            errordlg(ME.message,"ERRO","modal")
                        end  
                        
                     case 3   
                        try
                            dydxSym = diff(f(x));
                            g =@(x) eval(vectorize(char(dydxSym)));
                            [x,y,dydxDFP] = DFRegressivas_3(app,f,a,b,h);
                            dydxExata = g(x);
                            erroDFP = abs(dydxExata -dydxDFP);
                            tabela = array2table([x.',y.',dydxExata.', dydxDFP.',erroDFP.']);
                            app.UITable.ColumnName = {"X","Y","Derivada Exata","Derivada Aproximada","Erro"};
                            app.UITable.Data = tabela;
                            plot(app.UIAxes,x,y,"-r+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x, dydxExata, "-bo")
                            plot(app.UIAxes,x,dydxDFP,"-g*")
                            hold(app.UIAxes,"off")
                            formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                            cla(app.UIMostrador)
                            %app.UIMostrador.Toolbar.Visible = 'off';
                            text(app.UIMostrador,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)     
                          catch ME
                            errordlg(ME.message,"ERRO","modal")
                         end  
                    
                    case 4   
                        try
                            dydxSym = diff(f(x));
                            g =@(x) eval(vectorize(char(dydxSym)));
                            [x,y,dydxDFP] = DFProgressivas_3(app,f,a,b,h);
                            dydxExata = g(x);
                            erroDFP = abs(dydxExata -dydxDFP);
                            tabela = array2table([x.',y.',dydxExata.', dydxDFP.',erroDFP.']);
                            app.UITable.ColumnName = {"X","Y","Derivada Exata","Derivada Aproximada","Erro"};
                            app.UITable.Data = tabela;
                            plot(app.UIAxes,x,y,"-r+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x, dydxExata, "-bo")
                            plot(app.UIAxes,x,dydxDFP,"-g*")
                            hold(app.UIAxes,"off")
                            formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                            cla(app.UIMostrador)
                            %app.UIMostrador.Toolbar.Visible = 'off';
                            text(app.UIMostrador,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)     
                          catch ME
                            errordlg(ME.message,"ERRO","modal")
                        end  
                        
                     case 5   
                        try
                            dydxSym = diff(f(x));
                            g =@(x) eval(vectorize(char(dydxSym)));
                            [x,y,dydxDFP] = DFCentradas_3(app,f,a,b,h);
                            dydxExata = g(x);
                            erroDFP = abs(dydxExata -dydxDFP);
                            tabela = array2table([x.',y.',dydxExata.', dydxDFP.',erroDFP.']);
                            app.UITable.ColumnName = {"X","Y","Derivada Exata","Derivada Aproximada","Erro"};
                            app.UITable.Data = tabela;
                            plot(app.UIAxes,x,y,"-r+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x, dydxExata, "-bo")
                            plot(app.UIAxes,x,dydxDFP,"-g*")
                            hold(app.UIAxes,"off")
                            formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                            cla(app.UIMostrador)
                            %app.UIMostrador.Toolbar.Visible = 'off';
                            text(app.UIMostrador,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)     
                          catch ME
                            errordlg(ME.message,"ERRO","modal")
                        end  
                      case 6   
                        try
                            dydxSym = diff(f(x));
                            g =@(x) eval(vectorize(char(dydxSym)));
                            [x,y,dydxDFP] = DFDerivada_2(app,f,a,b,h);
                            dydxExata = g(x);
                            erroDFP = abs(dydxExata -dydxDFP);
                            tabela = array2table([x.',y.',dydxExata.', dydxDFP.',erroDFP.']);
                            app.UITable.ColumnName = {"X","Y","Derivada Exata","Derivada Aproximada","Erro"};
                            app.UITable.Data = tabela;
                            plot(app.UIAxes,x,y,"-r+")
                            hold(app.UIAxes,"on")
                            plot(app.UIAxes,x, dydxExata, "-bo")
                            plot(app.UIAxes,x,dydxDFP,"-g*")
                            hold(app.UIAxes,"off")
                            formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                            cla(app.UIMostrador)
                            %app.UIMostrador.Toolbar.Visible = 'off';
                            text(app.UIMostrador,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)     
                          catch ME
                            errordlg(ME.message,"ERRO","modal")
                        end  
            end  
            
                            escolhabgintNum = app.bginteNum.SelectedObject;
                            metodoSelecionado2 = find([app.simpson,...
                                     app.trapezios]==escolhabgintNum);
                            
                        switch metodoSelecionado2
                            case 1
                                   n=app.eN.Value;
                                    v = RSimpson(app,f,a,b,n);
                                    app.area.Value = abs(v); 
                                    x = a:0.1:b;
                                   
                                  area(x,y) 
                            
                            case 2
                                     n=app.eN.Value;
                                    k = RTrapezios(app,f,a,b,n);
                                    app.area.Value =abs(k); 
                                    x = a:0.1:b;
                                   
                                    area(x,y)
                              
                               
                        end 


        end

        % Button pushed function: bAtualizar_2
        function bAtualizar_2ButtonPushed(app, event)
            strFxy = app.eF_2.Value;
            f =@(x,y) eval(vectorize(strFxy));
            a = app.eA_2.Value; 
            h1 = app.eH_2.Value; 
            b = app.eB_2.Value;
            c = app.eA_3.Value; 
            h2 = app.eH_3.Value; 
            d = app.eB_3.Value;
            
            escolhabgMetodos = app.bgMetodos3.SelectedObject;
            metodoSelecionado = find([app.Harmonica,...
                                      ]==escolhabgMetodos);


            switch metodoSelecionado
                    case 1
                            [x,y] = meshgrid(a:h1:b, c:h2:d);
                            z = f(x,y);
                            %surf(app.UIAxes_2, x,y,z)
                            mesh(app.UIAxes_2, x,y,z)
                            if isHarmonica(app,f)
                                strHarmonica = "A função é Harmonica";
                                corHarmonica = "g";
                            else
                                strHarmonica = "A função não é Harmonica";
                                corHarmonica = "r";
                            end 
                            app.eHarmonica.Value=strHarmonica;
                            app.eHarmonica.FontColor=corHarmonica;


                end  


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.tgFrVr = uitab(app.TabGroup);
            app.tgFrVr.Title = 'Função real de variável real y = f(x)';
            app.pDados = uipanel(app.tgFrVr);
            app.Panel = uipanel(app.pDados);
            app.Panel.Title = 'Panel';
            app.FunoLabel = uilabel(app.Panel);
            app.FunoLabel.Text = 'Função';
            app.eF = uieditfield(app.Panel, 'text');
            app.eF.Value = 'sin(x)';
            app.eALabel = uilabel(app.Panel);
            app.eALabel.Text = 'eA';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eH = uieditfield(app.Panel, 'numeric');
            app.eH.Value = 0.1;
            app.eHLabel = uilabel(app.Panel);
            app.eHLabel.Text = 'eH';
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 6.28318530717959;
            app.eBLabel = uilabel(app.Panel);
            app.eBLabel.Text = 'eB';
            app.eN = uieditfield(app.Panel, 'numeric');
            app.eN.Value = 10;
            app.NLabel = uilabel(app.Panel);
            app.NLabel.Text = 'N';
            app.bAtualizar = uibutton(app.pDados, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.bgMetodos = uibuttongroup(app.pDados);
            app.bgMetodos.Title = 'Derivação númerica';
            app.Progressivas = uiradiobutton(app.bgMetodos);
            app.Progressivas.Text = 'Progressivas - 2 pontos';
            app.Progressivas.Value = true;
            app.Regressivas = uiradiobutton(app.bgMetodos);
            app.Regressivas.Text = 'Regressivas - 2 Pontos';
            app.Regressivas3 = uiradiobutton(app.bgMetodos);
            app.Regressivas3.Text = 'Regressivas - 3 pontos';
            app.Progressivas3 = uiradiobutton(app.bgMetodos);
            app.Progressivas3.Text = 'Progressivas - 3 pontos';
            app.Centradas3 = uiradiobutton(app.bgMetodos);
            app.Centradas3.Text = 'Centradas - 3 pontos';
            app.Derivada = uiradiobutton(app.bgMetodos);
            app.Derivada.Text = '2ª Derivada';
            app.bginteNum = uibuttongroup(app.pDados);
            app.bginteNum.Title = 'Integração Númerica';
            app.trapezios = uiradiobutton(app.bginteNum);
            app.trapezios.Text = 'Trapezios';
            app.trapezios.Value = true;
            app.simpson = uiradiobutton(app.bginteNum);
            app.simpson.Text = 'Simpson';
            app.reaEditFieldLabel = uilabel(app.bginteNum);
            app.reaEditFieldLabel.Text = 'Área';
            app.area = uieditfield(app.bginteNum, 'numeric');
            app.UIMostrador = uiaxes(app.pDados);
            app.UIMostrador.XTick = [];
            app.UIMostrador.YTick = [];
            app.pResultados = uipanel(app.tgFrVr);
            app.UITable = uitable(app.pResultados);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.pResultados);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.tgFr2Vr = uitab(app.TabGroup);
            app.tgFr2Vr.Title = 'Função real de 2 variáveis reais z = f(x,y)';
            app.pDados_2 = uipanel(app.tgFr2Vr);
            app.Panel_2 = uipanel(app.pDados_2);
            app.Panel_2.Title = 'Panel';
            app.fxyLabel = uilabel(app.Panel_2);
            app.fxyLabel.Text = 'f(x,y) = ';
            app.eF_2 = uieditfield(app.Panel_2, 'text');
            app.eF_2.Value = 'x^2+y^2';
            app.eA_2 = uieditfield(app.Panel_2, 'numeric');
            app.eA_2.Value = -2;
            app.eH_2 = uieditfield(app.Panel_2, 'numeric');
            app.eH_2.Value = 0.1;
            app.eB_2 = uieditfield(app.Panel_2, 'numeric');
            app.eB_2.Value = 2;
            app.eA_3 = uieditfield(app.Panel_2, 'numeric');
            app.eA_3.Value = -2;
            app.eH_3 = uieditfield(app.Panel_2, 'numeric');
            app.eH_3.Value = 0.1;
            app.eB_3 = uieditfield(app.Panel_2, 'numeric');
            app.eB_3.Value = 2;
            app.bAtualizar_2 = uibutton(app.pDados_2, 'push');
            app.bAtualizar_2.ButtonPushedFcn = createCallbackFcn(app, @bAtualizar_2ButtonPushed, true);
            app.bAtualizar_2.Text = 'Atializar';
            app.bgMetodos3 = uibuttongroup(app.pDados_2);
            app.bgMetodos3.Title = 'Button Group';
            app.Harmonica = uiradiobutton(app.bgMetodos3);
            app.Harmonica.Text = 'Harmonica';
            app.Harmonica.Value = true;
            app.eHarmonica = uieditfield(app.pDados_2, 'text');
            app.eHarmonica.Editable = 'off';
            app.pResultados_2 = uipanel(app.tgFr2Vr);
            app.UIAxes_2 = uiaxes(app.pResultados_2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.AutoresTab = uitab(app.TabGroup);
            app.AutoresTab.Title = 'Autores';
            app.Image = uiimage(app.AutoresTab);
            app.Image.ImageSource = 'LOGO-ISEC-IPC_sem-fundo-copy_fb.png';
            app.Image2 = uiimage(app.AutoresTab);
            app.Image2.ImageSource = 'diogo.jpg';
            app.Image3 = uiimage(app.AutoresTab);
            app.Image3.ImageSource = 'hugo.png';
            app.Image4 = uiimage(app.AutoresTab);
            app.Image4.ImageSource = 'ruben.jpg';
            app.DiogoSilva2020138473Label = uilabel(app.AutoresTab);
            app.DiogoSilva2020138473Label.Text = 'Diogo Silva -2020138473';
            app.HugoFerreira2020128305Label = uilabel(app.AutoresTab);
            app.HugoFerreira2020128305Label.Text = 'Hugo Ferreira - 2020128305';
            app.RbenMendes2020138473Label = uilabel(app.AutoresTab);
            app.RbenMendes2020138473Label.Text = 'Rúben Mendes- 2020138473';
            app.ISECEngenhariaInformticaLabel = uilabel(app.AutoresTab);
            app.ISECEngenhariaInformticaLabel.Text = 'ISEC - Engenharia Informática';
            app.AM2MatemticaComputacionalLabel = uilabel(app.AutoresTab);
            app.AM2MatemticaComputacionalLabel.Text = 'AM2 - Matemática Computacional ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquiaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end